classdef Q1_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CalculateButton          matlab.ui.control.Button
        degLabel_3               matlab.ui.control.Label
        thirdangle               matlab.ui.control.NumericEditField
        AngleLabel_3             matlab.ui.control.Label
        Axis3Label               matlab.ui.control.Label
        thirdaxis                matlab.ui.control.DropDown
        degLabel_2               matlab.ui.control.Label
        secondangle              matlab.ui.control.NumericEditField
        AngleLabel_2             matlab.ui.control.Label
        Axis2Label               matlab.ui.control.Label
        secondaxis               matlab.ui.control.DropDown
        degLabel                 matlab.ui.control.Label
        firstangle               matlab.ui.control.NumericEditField
        AngleLabel               matlab.ui.control.Label
        Axis1Label               matlab.ui.control.Label
        firstaxis                matlab.ui.control.DropDown
        RotationOrderLabel       matlab.ui.control.Label
        TimesecSliderLabel       matlab.ui.control.Label
        TimesecSlider            matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
        InitialOrientationLabel  matlab.ui.control.Label
        R10_11                   matlab.ui.control.NumericEditField
        R10_12                   matlab.ui.control.NumericEditField
        R10_13                   matlab.ui.control.NumericEditField
        R10_21                   matlab.ui.control.NumericEditField
        R10_22                   matlab.ui.control.NumericEditField
        R10_23                   matlab.ui.control.NumericEditField
        R10_31                   matlab.ui.control.NumericEditField
        R10_32                   matlab.ui.control.NumericEditField
        R10_33                   matlab.ui.control.NumericEditField
        FinalOrientationLabel    matlab.ui.control.Label
        MethodButtonGroup        matlab.ui.container.ButtonGroup
        AxisAngleButton          matlab.ui.control.ToggleButton
        RollPitchYawButton       matlab.ui.control.ToggleButton
        ZYXEulerAnglesButton     matlab.ui.control.ToggleButton
        RotationMatrixButton     matlab.ui.control.ToggleButton
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        Label_8                  matlab.ui.control.Label
        Label_9                  matlab.ui.control.Label
        ClearPlotButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        axis1 % first axis of rotation
        axis2 % second axis of rotation
        axis3 % third axis of rotation
        angle1 % first angle of rotation
        angle2 % second angle of rotation
        angle3 % third angle of rotation
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: R10_11
        function R10_11ValueChanged(app, event)
            value = app.R10_11.Value;
        end

        % Value changed function: R10_12
        function R10_12ValueChanged(app, event)
            value = app.R10_12.Value;
        end

        % Value changed function: R10_13
        function R10_13ValueChanged(app, event)
            value = app.R10_13.Value;
        end

        % Value changed function: R10_21
        function R10_21ValueChanged(app, event)
            value = app.R10_21.Value;
        end

        % Value changed function: R10_22
        function R10_22ValueChanged(app, event)
            value = app.R10_22.Value;
        end

        % Value changed function: R10_23
        function R10_23ValueChanged(app, event)
            value = app.R10_23.Value;
        end

        % Value changed function: R10_31
        function R10_31ValueChanged(app, event)
            value = app.R10_31.Value;
        end

        % Value changed function: R10_32
        function R10_32ValueChanged(app, event)
            value = app.R10_32.Value;
        end

        % Value changed function: R10_33
        function R10_33ValueChanged(app, event)
            value = app.R10_33.Value;
        end

        % Value changing function: TimesecSlider
        function TimesecSliderValueChanging(app, event)
            t = event.Value;
            r_int = zeros(3,3);
            r_initial = [[1 0 0];[0 1 0];[0 0 1]];
            r_final = [[app.R10_11.Value app.R10_12.Value app.R10_13.Value];[app.R10_21.Value app.R10_22.Value app.R10_23.Value];[app.R10_31.Value app.R10_32.Value app.R10_33.Value]];
            final_framex = r_final*[1;0;0];                                                                                                               
            final_framey = r_final*[0;1;0];
            final_framez = r_final*[0;0;1];
            if app.RotationMatrixButton.Value
                r_int(1,1) = (((r_final(1,1)-1)/(10))*t)+1;
                r_int(1,2) = (((r_final(1,2)-0)/(10))*t)+0;
                r_int(1,3) = (((r_final(1,3)-0)/(10))*t)+0;
                r_int(2,1) = (((r_final(2,1)-0)/(10))*t)+0;
                r_int(2,2) = (((r_final(2,2)-1)/(10))*t)+1;
                r_int(2,3) = (((r_final(2,3)-0)/(10))*t)+0;
                r_int(3,1) = (((r_final(3,1)-0)/(10))*t)+0;
                r_int(3,2) = (((r_final(3,2)-0)/(10))*t)+0;
                r_int(3,3) = (((r_final(3,3)-1)/(10))*t)+1;
                %unit vectors along intermediate frames
                new_framex = r_int*[1;0;0];
                new_framey = r_int*[0;1;0];
                new_framez = r_int*[0;0;1];
                %plotting the results
                quiver3(app.UIAxes,0,0,0,1,0,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,1,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,0,1,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framex(1,1),final_framex(2,1),final_framex(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framey(1,1),final_framey(2,1),final_framey(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framez(1,1),final_framez(2,1),final_framez(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framex(1,1),new_framex(2,1),new_framex(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framey(1,1),new_framey(2,1),new_framey(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framez(1,1),new_framez(2,1),new_framez(3,1),'b');
            elseif app.RollPitchYawButton.Value
                r_int = zeros(1,3);
                r_initial_rpy = rotm2eul(r_initial,'XYZ');
                r_final_rpy = rotm2eul(r_final,'XYZ');
                r_int(1,1) = (((r_final_rpy(1,1)-r_initial_rpy(1,1))/(10))*t)+(r_initial_rpy(1,1));
                r_int(1,2) = (((r_final_rpy(1,2)-r_initial_rpy(1,2))/(10))*t)+(r_initial_rpy(1,2));
                r_int(1,3) = (((r_final_rpy(1,3)-r_initial_rpy(1,3))/(10))*t)+(r_initial_rpy(1,3));
                
                %constructing the rotation matrix using the euler angles
                syms phi theta Psi real
                Rxphi   = [[1 0 0];[0 cos(phi) -sin(phi)];[0 sin(phi) cos(phi)]];
                Rytheta = [[cos(theta) 0 sin(theta)];[0 1 0];[-sin(theta) 0 cos(theta)]];
                Rzpsi   = [[cos(Psi) -sin(Psi) 0];[sin(Psi) cos(Psi) 0];[0 0 1]];
                Rxyz    = Rxphi*Rytheta*Rzpsi;
                int_rotm = double(subs(Rxyz,{phi,theta,Psi},{r_int(1,1),r_int(1,2),r_int(1,3)}));
                %unit vectors along intermediate frames
                new_framex = int_rotm*[1;0;0];
                new_framey = int_rotm*[0;1;0];
                new_framez = int_rotm*[0;0;1];
                %plotting the results
                quiver3(app.UIAxes,0,0,0,1,0,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,1,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,0,1,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framex(1,1),final_framex(2,1),final_framex(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framey(1,1),final_framey(2,1),final_framey(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framez(1,1),final_framez(2,1),final_framez(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framex(1,1),new_framex(2,1),new_framex(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framey(1,1),new_framey(2,1),new_framey(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framez(1,1),new_framez(2,1),new_framez(3,1),'b');
            elseif app.ZYXEulerAnglesButton.Value
                r_int = zeros(1,3);
                r_initial_zyx = rotm2eul(r_initial,'ZYX');
                r_final_zyx = rotm2eul(r_final,'ZYX');
                r_int(1,1) = (((r_final_zyx(1,1)-r_initial_zyx(1,1))/(10))*t)+(r_initial_zyx(1,1));
                r_int(1,2) = (((r_final_zyx(1,2)-r_initial_zyx(1,2))/(10))*t)+(r_initial_zyx(1,2));
                r_int(1,3) = (((r_final_zyx(1,3)-r_initial_zyx(1,3))/(10))*t)+(r_initial_zyx(1,3));
                
                %constructing the rotation matrix using the euler angles
                syms phi theta Psi real
                Rzphi   = [[cos(phi) -sin(phi) 0];
                           [sin(phi) cos(phi) 0];
                           [0 0 1]];
                Rytheta = [[cos(theta) 0 sin(theta)];
                           [0 1 0];
                           [-sin(theta) 0 cos(theta)]];
                Rxpsi   = [[1 0 0];
                           [0 cos(Psi) -sin(Psi)];
                           [0 sin(Psi) cos(Psi)]];
                Rzyx    = Rzphi*Rytheta*Rxpsi;              
                int_rotm = double(subs(Rzyx,{phi,theta,Psi},{r_int(1,1),r_int(1,2),r_int(1,3)}));
                % unit vectors along intermediate frames
                new_framex = int_rotm*[1;0;0];
                new_framey = int_rotm*[0;1;0];
                new_framez = int_rotm*[0;0;1];
                %plotting the results
                quiver3(app.UIAxes,0,0,0,1,0,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,1,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,0,1,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framex(1,1),final_framex(2,1),final_framex(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framey(1,1),final_framey(2,1),final_framey(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framez(1,1),final_framez(2,1),final_framez(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framex(1,1),new_framex(2,1),new_framex(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framey(1,1),new_framey(2,1),new_framey(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framez(1,1),new_framez(2,1),new_framez(3,1),'b');
            elseif app.AxisAngleButton.Value
                r_int = zeros(1,4);
                r_axangi = rotm2axang(r_initial);   %initial axis angle
                r_axangf = rotm2axang(r_final);     %final axis angle
                r_int(1,1) = (((r_axangf(1,1)-r_axangi(1,1))/(10))*t)+(r_axangi(1,1));
                r_int(1,2) = (((r_axangf(1,2)-r_axangi(1,2))/(10))*t)+(r_axangi(1,2));
                r_int(1,3) = (((r_axangf(1,3)-r_axangi(1,3))/(10))*t)+(r_axangi(1,3));
                r_int(1,4) = (((r_axangf(1,4)-r_axangi(1,4))/(10))*t)+(r_axangi(1,4));
                %constructing the rotation matrix using the axis-angle
                syms kx ky kz theta real
                Raxang = [[kx^2*(1-cos(theta))+cos(theta) kx*ky*(1-cos(theta))-kz*sin(theta) kx*kz*(1-cos(theta))+ky*sin(theta)];
                          [kx*ky*(1-cos(theta))+kz*sin(theta) ky^2*(1-cos(theta))+cos(theta) ky*kz*(1-cos(theta))-kx*sin(theta)];
                          [kx*kz*(1-cos(theta))-ky*sin(theta) ky*kz*(1-cos(theta))+kx*sin(theta) kz^2*(1-cos(theta))+cos(theta)]];
                int_rotm = double(subs(Raxang,{kx,ky,kz,theta},{r_int(1,1),r_int(1,2),r_int(1,3),r_int(1,4)}));
                % unit vectors along intermediate frames
                new_framex = int_rotm*[1;0;0];
                new_framey = int_rotm*[0;1;0];
                new_framez = int_rotm*[0;0;1];
                %plotting the results
                quiver3(app.UIAxes,0,0,0,1,0,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,1,0,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,0,0,1,'r');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framex(1,1),final_framex(2,1),final_framex(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framey(1,1),final_framey(2,1),final_framey(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,final_framez(1,1),final_framez(2,1),final_framez(3,1),'k');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framex(1,1),new_framex(2,1),new_framex(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framey(1,1),new_framey(2,1),new_framey(3,1),'b');
                hold (app.UIAxes,'on')
                quiver3(app.UIAxes,0,0,0,new_framez(1,1),new_framez(2,1),new_framez(3,1),'b');
                
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes); %resets the figure
        end

        % Value changed function: firstaxis
        function firstaxisValueChanged(app, event)
            value = app.firstaxis.Value;
            app.axis1 = value;
        end

        % Value changed function: secondaxis
        function secondaxisValueChanged(app, event)
            value = app.secondaxis.Value;
            app.axis2 = value;
        end

        % Value changed function: thirdaxis
        function thirdaxisValueChanged(app, event)
            value = app.thirdaxis.Value;
            app.axis3 = value;
        end

        % Value changed function: firstangle
        function firstangleValueChanged(app, event)
            value = app.firstangle.Value;
            app.angle1 = value;
        end

        % Value changed function: secondangle
        function secondangleValueChanged(app, event)
            value = app.secondangle.Value;
            app.angle2 = value;
        end

        % Value changed function: thirdangle
        function thirdangleValueChanged(app, event)
            value = app.thirdangle.Value;
            app.angle3 = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if strcmp(app.axis1,'X')
                R1 = [[1 0 0];[0 cosd(app.angle1) -sind(app.angle1)];[0 sind(app.angle1) cosd(app.angle1)]];
            elseif strcmp(app.axis1,'Y')
                R1 = [[cosd(app.angle1) 0 sind(app.angle1)];[0 1 0];[-sind(app.angle1) 0 cosd(app.angle1)]];
            elseif strcmp(app.axis1,'Z')
                R1 = [[cosd(app.angle1) -sind(app.angle1) 0];[sind(app.angle1) cosd(app.angle1) 0];[0 0 1]];
            end
            if strcmp(app.axis2,'X')
                R2 = [[1 0 0];[0 cosd(app.angle2) -sind(app.angle2)];[0 sind(app.angle2) cosd(app.angle2)]];
            elseif strcmp(app.axis2,'Y')
                R2 = [[cosd(app.angle2) 0 sind(app.angle2)];[0 1 0];[-sind(app.angle2) 0 cosd(app.angle2)]];
            elseif strcmp(app.axis2,'Z')
                R2 = [[cosd(app.angle2) -sind(app.angle2) 0];[sind(app.angle2) cosd(app.angle2) 0];[0 0 1]];
            end
            if strcmp(app.axis3,'X')
                R3 = [[1 0 0];[0 cosd(app.angle3) -sind(app.angle3)];[0 sind(app.angle3) cosd(app.angle3)]];
            elseif strcmp(app.axis3,'Y')
                R3 = [[cosd(app.angle3) 0 sind(app.angle3)];[0 1 0];[-sind(app.angle3) 0 cosd(app.angle3)]];
            elseif strcmp(app.axis3,'Z')
                R3 = [[cosd(app.angle3) -sind(app.angle3) 0];[sind(app.angle3) cosd(app.angle3) 0];[0 0 1]];
            end
            
            %calculating the resultant rotation matrix
            R = R1*R2*R3;
            
            %assigning values to the final orientation field
            app.R10_11.Value = R(1,1);
            app.R10_12.Value = R(1,2);
            app.R10_13.Value = R(1,3);
            app.R10_21.Value = R(2,1);
            app.R10_22.Value = R(2,2);
            app.R10_23.Value = R(2,3);
            app.R10_31.Value = R(3,1);
            app.R10_32.Value = R(3,2);
            app.R10_33.Value = R(3,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '1';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '0';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '0';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '0';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '1';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '0';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.Title = 'Method';
            app.RotationMatrixButton = uitogglebutton(app.MethodButtonGroup);
            app.RotationMatrixButton.Text = 'Rotation Matrix';
            app.RotationMatrixButton.Value = true;
            app.ZYXEulerAnglesButton = uitogglebutton(app.MethodButtonGroup);
            app.ZYXEulerAnglesButton.Text = 'ZYX Euler Angles';
            app.RollPitchYawButton = uitogglebutton(app.MethodButtonGroup);
            app.RollPitchYawButton.Text = 'Roll-Pitch-Yaw';
            app.AxisAngleButton = uitogglebutton(app.MethodButtonGroup);
            app.AxisAngleButton.Text = 'Axis-Angle';
            app.FinalOrientationLabel = uilabel(app.UIFigure);
            app.FinalOrientationLabel.Text = 'Final Orientation';
            app.R10_33 = uieditfield(app.UIFigure, 'numeric');
            app.R10_33.ValueChangedFcn = createCallbackFcn(app, @R10_33ValueChanged, true);
            app.R10_32 = uieditfield(app.UIFigure, 'numeric');
            app.R10_32.ValueChangedFcn = createCallbackFcn(app, @R10_32ValueChanged, true);
            app.R10_31 = uieditfield(app.UIFigure, 'numeric');
            app.R10_31.ValueChangedFcn = createCallbackFcn(app, @R10_31ValueChanged, true);
            app.R10_23 = uieditfield(app.UIFigure, 'numeric');
            app.R10_23.ValueChangedFcn = createCallbackFcn(app, @R10_23ValueChanged, true);
            app.R10_22 = uieditfield(app.UIFigure, 'numeric');
            app.R10_22.ValueChangedFcn = createCallbackFcn(app, @R10_22ValueChanged, true);
            app.R10_21 = uieditfield(app.UIFigure, 'numeric');
            app.R10_21.ValueChangedFcn = createCallbackFcn(app, @R10_21ValueChanged, true);
            app.R10_13 = uieditfield(app.UIFigure, 'numeric');
            app.R10_13.ValueChangedFcn = createCallbackFcn(app, @R10_13ValueChanged, true);
            app.R10_12 = uieditfield(app.UIFigure, 'numeric');
            app.R10_12.ValueChangedFcn = createCallbackFcn(app, @R10_12ValueChanged, true);
            app.R10_11 = uieditfield(app.UIFigure, 'numeric');
            app.R10_11.ValueChangedFcn = createCallbackFcn(app, @R10_11ValueChanged, true);
            app.InitialOrientationLabel = uilabel(app.UIFigure);
            app.InitialOrientationLabel.Text = 'Initial Orientation';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orientation Plots')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimesecSlider = uislider(app.UIFigure);
            app.TimesecSlider.Limits = [0 10];
            app.TimesecSlider.ValueChangingFcn = createCallbackFcn(app, @TimesecSliderValueChanging, true);
            app.TimesecSliderLabel = uilabel(app.UIFigure);
            app.TimesecSliderLabel.Text = 'Time(sec)';
            app.RotationOrderLabel = uilabel(app.UIFigure);
            app.RotationOrderLabel.Text = 'Rotation Order';
            app.firstaxis = uidropdown(app.UIFigure);
            app.firstaxis.Items = {'X', 'Y', 'Z'};
            app.firstaxis.ValueChangedFcn = createCallbackFcn(app, @firstaxisValueChanged, true);
            app.firstaxis.Value = 'X';
            app.Axis1Label = uilabel(app.UIFigure);
            app.Axis1Label.Text = 'Axis 1';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle';
            app.firstangle = uieditfield(app.UIFigure, 'numeric');
            app.firstangle.ValueChangedFcn = createCallbackFcn(app, @firstangleValueChanged, true);
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.secondaxis = uidropdown(app.UIFigure);
            app.secondaxis.Items = {'X', 'Y', 'Z'};
            app.secondaxis.ValueChangedFcn = createCallbackFcn(app, @secondaxisValueChanged, true);
            app.secondaxis.Value = 'X';
            app.Axis2Label = uilabel(app.UIFigure);
            app.Axis2Label.Text = 'Axis 2';
            app.AngleLabel_2 = uilabel(app.UIFigure);
            app.AngleLabel_2.Text = 'Angle';
            app.secondangle = uieditfield(app.UIFigure, 'numeric');
            app.secondangle.ValueChangedFcn = createCallbackFcn(app, @secondangleValueChanged, true);
            app.degLabel_2 = uilabel(app.UIFigure);
            app.degLabel_2.Text = 'deg';
            app.thirdaxis = uidropdown(app.UIFigure);
            app.thirdaxis.Items = {'X', 'Y', 'Z'};
            app.thirdaxis.ValueChangedFcn = createCallbackFcn(app, @thirdaxisValueChanged, true);
            app.thirdaxis.Value = 'X';
            app.Axis3Label = uilabel(app.UIFigure);
            app.Axis3Label.Text = 'Axis 3';
            app.AngleLabel_3 = uilabel(app.UIFigure);
            app.AngleLabel_3.Text = 'Angle';
            app.thirdangle = uieditfield(app.UIFigure, 'numeric');
            app.thirdangle.ValueChangedFcn = createCallbackFcn(app, @thirdangleValueChanged, true);
            app.degLabel_3 = uilabel(app.UIFigure);
            app.degLabel_3.Text = 'deg';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q1_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end