classdef Q2_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        l2                           matlab.ui.control.NumericEditField
        l1                           matlab.ui.control.NumericEditField
        Link2lengthLabel             matlab.ui.control.Label
        Link1lengthLabel             matlab.ui.control.Label
        PressPlottoplotandClearPlottocleartheplotLabel  matlab.ui.control.Label
        PicktheparametersthatneedtobeplottedLabel  matlab.ui.control.Label
        ConfigSwitch                 matlab.ui.control.ToggleSwitch
        polynomialovertheta1vsttheta2vstLabel  matlab.ui.control.Label
        ivJointspacequinticmethodfitsaquinticLabel  matlab.ui.control.Label
        polynomialoverxvstyvstLabel  matlab.ui.control.Label
        iiiCartesianspacequinticmethodfitsaquinticLabel  matlab.ui.control.Label
        linearlyinterpolatesthejointanglesLabel  matlab.ui.control.Label
        iiJointspacelinearinterpolationmethodLabel  matlab.ui.control.Label
        linearlyinterpolatestheendeffectorcoordinatesLabel  matlab.ui.control.Label
        iCartesianspacelinearinterpolationmethodLabel  matlab.ui.control.Label
        SelectthemethodofestimatingthetrajectoryLabel  matlab.ui.control.Label
        EnterthestartandendpositioncoordinatesLabel  matlab.ui.control.Label
        UsingtheGUILabel             matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        y10                          matlab.ui.control.NumericEditField
        x10                          matlab.ui.control.NumericEditField
        y0                           matlab.ui.control.NumericEditField
        x0                           matlab.ui.control.NumericEditField
        EndPositiont10sLabel         matlab.ui.control.Label
        StartPositiont0sLabel        matlab.ui.control.Label
        GraphDropDown                matlab.ui.control.DropDown
        GraphDropDownLabel           matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        ClearPlotButton              matlab.ui.control.Button
        trajMethod                   matlab.ui.container.ButtonGroup
        jointQuintic                 matlab.ui.control.ToggleButton
        carQuintic                   matlab.ui.control.ToggleButton
        jointLinear                  matlab.ui.control.ToggleButton
        carLinear                    matlab.ui.control.ToggleButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X0 % initial x-coordinate @ t=0s
        Y0 % initial y-coordinate @ t=0s
        X10 % initial x-coordinate @ t=10s
        Y10 % initial y-coordinate @ t=10s
        graph % x-axis for plotting
        config % crossed or uncrossed
        L1 % link length 1
        L2 % link length 2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: x0
        function x0ValueChanged(app, event)
            value = app.x0.Value;
            app.X0 = value;
        end

        % Value changed function: y0
        function y0ValueChanged(app, event)
            value = app.y0.Value;
            app.Y0 = value;
        end

        % Value changed function: x10
        function x10ValueChanged(app, event)
            value = app.x10.Value;
            app.X10 = value;
        end

        % Value changed function: y10
        function y10ValueChanged(app, event)
            value = app.y10.Value;
            app.Y10 = value;
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            app.graph = value;
        end

        % Value changed function: ConfigSwitch
        function ConfigSwitchValueChanged(app, event)
            value = app.ConfigSwitch.Value;
            app.config = value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if app.carLinear.Value
                p0  = [app.X0 app.Y0];    %position at t=0s
                p10 = [app.X10 app.Y10];  %position at t=10s
                L   = [app.L1 app.L2];    %link-length vector
                
                %creating the intermediate positions considering a linear trajectory
                t = (1:1:9)'; %positions will be calculated every second
                pe_int = zeros(1,2,length(t));  %intermediate coordinates for the end effector
                for i = (1:1:length(t))
                    for j = (1:1:size(p0,1))
                        for k = (1:1:size(p0,2))
                            pe_int(j,k,i) = (((p10(j,k) - p0(j,k))/10)*t(i,1))+(p0(j,k));
                        end
                    end
                end
                p1 = pe_int(:,:,1);
                p2 = pe_int(:,:,2);
                p3 = pe_int(:,:,3);
                p4 = pe_int(:,:,4);
                p5 = pe_int(:,:,5);
                p6 = pe_int(:,:,6);
                p7 = pe_int(:,:,7);
                p8 = pe_int(:,:,8);
                p9 = pe_int(:,:,9);
                P  = [p0;p1;p2;p3;p4;p5;p6;p7;p8;p9;p10]; %vector for all the end effector positions
                
                % calculating the theta values for these intermediate positions using the
                % function 'invkinema.m'
                theta1_u = zeros(length(P),1);
                theta2_u = zeros(length(P),1);
                theta1_c = zeros(length(P),1);
                theta2_c = zeros(length(P),1);
                
                if strcmp(app.config,'Uncrossed')
                    for i = (1:1:length(P))
                        [theta1_u(i,1),theta2_u(i,1)] = invkinema(L,P(i,1),P(i,2),1);
                    end
                    if strcmp(app.graph,'Xe v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,P(:,1),'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,P(:,2),'r-');
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,P(:,1),P(:,2),'r-')
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta1_u,'r-');
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta2_u,'r-');
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end                    
                elseif strcmp(app.config,'Crossed')
                    for i = (1:1:length(P))
                        [theta1_c(i,1),theta2_c(i,1)] = invkinema(L,P(i,1),P(i,2),-1);
                    end
                    if strcmp(app.graph,'Xe v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,P(:,1),'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,P(:,2),'r-');
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,P(:,1),P(:,2),'r-');
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta1_c,'r-');
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta2_c,'r-');
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end
                end
            elseif app.jointLinear.Value
                p0  = [app.X0 app.Y0];   %position at t=0s
                p10 = [app.X10 app.Y10];    %position at t=10s
                L   = [app.L1 app.L2];    %link-length vector
                
                %calculating the joint 2 coordinates using the function 'RR_InvPosKin.m'
                %corresponding to p0 and p10
                
                [theta1_u0,theta2_u0] = invkinema(L,p0(1,1),p0(1,2),1);      %theta values at t=0,  uncrossed
                [theta1_u10,theta2_u10] = invkinema(L,p10(1,1),p10(1,2),1);  %theta values at t=10, uncrossed
                [theta1_c0,theta2_c0] = invkinema(L,p0(1,1),p0(1,2),-1);     %theta values at t=0, crossed
                [theta1_c10,theta2_c10] = invkinema(L,p10(1,1),p10(1,2),-1); %theta values at t=10, crossed
                
                %% uncrossed configuration
                if strcmp(app.config,'Uncrossed')
                    t = (1:1:9)';
                    theta1_uint = zeros(1,1,length(t));  %3D matrix to store the interim theta1(uncrossed)
                    theta2_uint = zeros(1,1,length(t));  %3D matrix to store the interim theta2(uncrossed)
                    
                    for i = (1:1:length(t))
                        theta1_uint(1,1,i) = (((theta1_u10-theta1_u0)/10)*t(i,1))+theta1_u0;
                        theta2_uint(1,1,i) = (((theta2_u10-theta2_u0)/10)*t(i,1))+theta2_u0;
                    end
                    theta1_u1 = theta1_uint(1,1,1);
                    theta1_u2 = theta1_uint(1,1,2);
                    theta1_u3 = theta1_uint(1,1,3);
                    theta1_u4 = theta1_uint(1,1,4);
                    theta1_u5 = theta1_uint(1,1,5);
                    theta1_u6 = theta1_uint(1,1,6);
                    theta1_u7 = theta1_uint(1,1,7);
                    theta1_u8 = theta1_uint(1,1,8);
                    theta1_u9 = theta1_uint(1,1,9);
                    theta1_u = [theta1_u0;theta1_u1;theta1_u2;theta1_u3;theta1_u4;theta1_u5;theta1_u6;theta1_u7;theta1_u8;theta1_u9;theta1_u10];
                    
                    theta2_u1 = theta2_uint(1,1,1);
                    theta2_u2 = theta2_uint(1,1,2);
                    theta2_u3 = theta2_uint(1,1,3);
                    theta2_u4 = theta2_uint(1,1,4);
                    theta2_u5 = theta2_uint(1,1,5);
                    theta2_u6 = theta2_uint(1,1,6);
                    theta2_u7 = theta2_uint(1,1,7);
                    theta2_u8 = theta2_uint(1,1,8);
                    theta2_u9 = theta2_uint(1,1,9);
                    theta2_u = [theta2_u0;theta2_u1;theta2_u2;theta2_u3;theta2_u4;theta2_u5;theta2_u6;theta2_u7;theta2_u8;theta2_u9;theta2_u10];
                    
                    j2_u = zeros(length(theta1_u),2); %initialize a 2D matrix to store all the joint 2 coordinates (uncrossed)
                    ee_u = zeros(length(theta1_u),2); %initialize a 2D matrix to store all the end effector coordinates (uncrossed)
                    for i = (1:1:length(theta1_u))
                        j2_u(i,1) = L(1)*cosd(theta1_u(i,1));
                        j2_u(i,2) = L(1)*sind(theta1_u(i,1));
                        ee_u(i,1) = L(1)*cosd(theta1_u(i,1)) + L(2)*cosd(theta2_u(i,1));
                        ee_u(i,2) = L(1)*sind(theta1_u(i,1)) + L(2)*sind(theta2_u(i,1));
                    end
                    if strcmp(app.graph,'Xe v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,ee_u(:,1),'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,ee_u(:,2),'r-')
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,ee_u(:,1),ee_u(:,2),'r-');
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta1_u,'r-');
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta2_u,'r-');
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end
                elseif strcmp(app.config,'Crossed')
                    t = (0:1:10)';
                    theta1_cint = zeros(1,1,length(t));  %3D matrix to store the interim theta1(crossed)
                    theta2_cint = zeros(1,1,length(t));  %3D matrix to store the interim theta2(crossed)
                    
                    for i = (1:1:length(t))
                        theta1_cint(1,1,i) = (((theta1_c10-theta1_c0)/10)*t(i,1))+theta1_c0;
                        theta2_cint(1,1,i) = (((theta2_c10-theta2_c0)/10)*t(i,1))+theta2_c0;
                    end
                    
                    theta1_c1 = theta1_cint(1,1,1);
                    theta1_c2 = theta1_cint(1,1,2);
                    theta1_c3 = theta1_cint(1,1,3);
                    theta1_c4 = theta1_cint(1,1,4);
                    theta1_c5 = theta1_cint(1,1,5);
                    theta1_c6 = theta1_cint(1,1,6);
                    theta1_c7 = theta1_cint(1,1,7);
                    theta1_c8 = theta1_cint(1,1,8);
                    theta1_c9 = theta1_cint(1,1,9);
                    theta1_c = [theta1_c0;theta1_c1;theta1_c2;theta1_c3;theta1_c4;theta1_c5;theta1_c6;theta1_c7;theta1_c8;theta1_c9;theta1_c10];
                    
                    theta2_c1 = theta2_cint(1,1,1);
                    theta2_c2 = theta2_cint(1,1,2);
                    theta2_c3 = theta2_cint(1,1,3);
                    theta2_c4 = theta2_cint(1,1,4);
                    theta2_c5 = theta2_cint(1,1,5);
                    theta2_c6 = theta2_cint(1,1,6);
                    theta2_c7 = theta2_cint(1,1,7);
                    theta2_c8 = theta2_cint(1,1,8);
                    theta2_c9 = theta2_cint(1,1,9);
                    theta2_c = [theta2_c0;theta2_c1;theta2_c2;theta2_c3;theta2_c4;theta2_c5;theta2_c6;theta2_c7;theta2_c8;theta2_c9;theta2_c10];
                    
                    %coordinates of joint 2 and end effector (uncrossed)
                    j2_c = zeros(length(theta1_c),2); %initialize a 2D matrix to store all the joint 2 coordinates (uncrossed)
                    ee_c = zeros(length(theta1_c),2); %initialize a 2D matrix to store all the end effector coordinates (uncrossed)
                    for i = (1:1:length(theta1_c))
                        j2_c(i,1) = L(1)*cosd(theta1_c(i,1));
                        j2_c(i,2) = L(1)*sind(theta1_c(i,1));
                        ee_c(i,1) = L(1)*cosd(theta1_c(i,1)) + L(2)*cosd(theta2_c(i,1));
                        ee_c(i,2) = L(1)*sind(theta1_c(i,1)) + L(2)*sind(theta2_c(i,1));
                    end
                    if strcmp(app.graph,'Xe v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,ee_c(:,1),'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,ee_c(:,2),'r-');
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,ee_c(:,1),ee_c(:,2),'r-')
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta1_c,'r-')
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,T,theta2_c,'r-')
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end
                end
            elseif app.carQuintic.Value
                AY = [[0 0 0 0 0 1];
                      [10^5 10^4 10^3 10^2 10 1];
                      [0 0 0 0 1 0];
                      [5*10^4 4*10^3 3*10^2 20 1 0];
                      [0 0 0 1 0 0];
                      [20*10^3 12*10^2 60 2 0 0]];
                BY = [1;1;0;0;0;0];
                Y = AY\BY;
                AX = [[0 0 0 0 0 1];
                      [10^5 10^4 10^3 10^2 10 1];
                      [0 0 0 0 1 0];
                      [5*10^4 4*10^3 3*10^2 20 1 0];
                      [0 0 0 1 0 0];
                      [20*10^3 12*10^2 60 2 0 0]];
                BX =  [-1;2;0;0;0;0];
                X = AX\BX;
                
                %using the quintic equations to estimate the position
                t = (0:0.01:10)';
                L = [2 1];
                Xe = zeros(length(t),1);
                Ye = zeros(length(t),1);
                for i = (1:1:length(t))
                    Xe(i,1) = X(1,1)*(t(i,1))^5+X(2,1)*(t(i,1))^4+X(3,1)*(t(i,1))^3+X(4,1)*(t(i,1))^2+X(5,1)*(t(i,1))+X(6,1);
                    Ye(i,1) = Y(1,1)*(t(i,1))^5+Y(2,1)*(t(i,1))^4+Y(3,1)*(t(i,1))^3+Y(4,1)*(t(i,1))^2+Y(5,1)*(t(i,1))+Y(6,1);
                end
                
                %finding the theta1 and theta2 values at these end effector positions
                theta1_u = zeros(length(t),1);
                theta1_c = zeros(length(t),1);
                theta2_u = zeros(length(t),1);
                theta2_c = zeros(length(t),1);
                
                if strcmp(app.config,'Uncrossed')
                    [theta1_u,theta2_u] = invkinema(L,Xe,Ye,1);
                    if strcmp(app.graph,'Xe v/s t')
                        plot(app.UIAxes,t,Xe,'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        plot(app.UIAxes,t,Ye,'r-');
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,Xe,Ye,'r-')
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        plot(app.UIAxes,t,theta1_u,'r-')
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        plot(app.UIAxes,t,theta2_u,'r-')
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end
                elseif strcmp(app.config,'Crossed')
                    [theta1_c,theta2_c] = invkinema(L,Xe,Ye,-1);
                    if strcmp(app.graph,'Xe v/s t')
                        plot(app.UIAxes,t,Xe,'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        plot(app.UIAxes,t,Ye,'r-');
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,Xe,Ye,'r-');
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        plot(app.UIAxes,t,theta1_c,'r-');
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        plot(app.UIAxes,t,theta2_c,'r-');
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end
                end
                
            elseif app.jointQuintic.Value
                p0  = [-1 1];
                p10 = [2 1];
                L = [2 1];
                [theta1_u0,theta2_u0] = invkinema(L,p0(1),p0(2),1);
                [theta1_c0,theta2_c0] = invkinema(L,p0(1),p0(2),-1);
                [theta1_u10,theta2_u10] = invkinema(L,p10(1),p10(2),1);
                [theta1_c10,theta2_c10] = invkinema(L,p10(1),p10(2),-1);
                A = [[0 0 0 0 0 1];
                     [10^5 10^4 10^3 10^2 10 1];
                     [0 0 0 0 1 0];
                     [5*10^4 4*10^3 3*10^2 20 1 0];
                     [0 0 0 1 0 0];
                     [20*10^3 12*10^2 60 2 0 0]];
                B_theta1_u = [theta1_u0;theta1_u10;0;0;0;0];
                B_theta1_c = [theta1_c0;theta1_c10;0;0;0;0];
                B_theta2_u = [theta2_u0;theta2_u10;0;0;0;0];
                B_theta2_c = [theta2_c0;theta2_c10;0;0;0;0];
                %coeffecients
                C_theta1_u = A\B_theta1_u;
                C_theta1_c = A\B_theta1_c;
                C_theta2_u = A\B_theta2_u;
                C_theta2_c = A\B_theta2_c;
                t = (0:0.01:10)';

                for i = (1:1:length(t))
                    theta1_u(i,1) = C_theta1_u(1,1)*(t(i,1))^5+C_theta1_u(2,1)*(t(i,1))^4+C_theta1_u(3,1)*(t(i,1))^3+C_theta1_u(4,1)*(t(i,1))^2+C_theta1_u(5,1)*(t(i,1))+C_theta1_u(6,1);
                    theta1_c(i,1) = C_theta1_c(1,1)*(t(i,1))^5+C_theta1_c(2,1)*(t(i,1))^4+C_theta1_c(3,1)*(t(i,1))^3+C_theta1_c(4,1)*(t(i,1))^2+C_theta1_c(5,1)*(t(i,1))+C_theta1_c(6,1);
                    theta2_u(i,1) = C_theta2_u(1,1)*(t(i,1))^5+C_theta2_u(2,1)*(t(i,1))^4+C_theta2_u(3,1)*(t(i,1))^3+C_theta2_u(4,1)*(t(i,1))^2+C_theta2_u(5,1)*(t(i,1))+C_theta2_u(6,1);
                    theta2_c(i,1) = C_theta2_c(1,1)*(t(i,1))^5+C_theta2_c(2,1)*(t(i,1))^4+C_theta2_c(3,1)*(t(i,1))^3+C_theta2_c(4,1)*(t(i,1))^2+C_theta2_c(5,1)*(t(i,1))+C_theta2_c(6,1);
                end
                ee_xu = L(1)*cosd(theta1_u) + L(2)*cosd(theta2_u);
                ee_yu = L(1)*sind(theta1_u) + L(2)*sind(theta2_u);
                ee_xc = L(1)*cosd(theta1_c) + L(2)*cosd(theta2_c);
                ee_yc = L(1)*sind(theta1_c) + L(2)*sind(theta2_c);
                
                if strcmp(app.config,'Uncrossed')
                    if strcmp(app.graph,'Xe v/s t')
                        plot(app.UIAxes,t,ee_xu,'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        plot(app.UIAxes,t,ee_yu,'r-');
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,ee_xu,ee_yu,'r-')
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        plot(app.UIAxes,t,theta1_u,'r-')
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,t,theta2_u,'r-')
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end
                elseif strcmp(app.config,'Crossed')
                    if strcmp(app.graph,'Xe v/s t')
                        plot(app.UIAxes,t,ee_xc,'r-');
                        title(app.UIAxes,'Xe v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Xe');
                    elseif strcmp(app.graph,'Ye v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,t,ee_yc,'r-');
                        title(app.UIAxes,'Ye v/s T');
                        xlabel(app.UIAxes,'Time(sec)');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Ye v/s Xe')
                        plot(app.UIAxes,ee_xc,ee_yc,'r-');
                        title(app.UIAxes,'Ye v/s Xe');
                        xlabel(app.UIAxes,'Xe');
                        ylabel(app.UIAxes,'Ye');
                    elseif strcmp(app.graph,'Theta1 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,t,theta1_c,'r-');
                        title(app.UIAxes,'Theta1 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta1');
                    elseif strcmp(app.graph,'Theta2 v/s t')
                        T = (0:1:10)';
                        plot(app.UIAxes,t,theta2_c,'r-');
                        title(app.UIAxes,'Theta2 v/s t');
                        xlabel(app.UIAxes,'t');
                        ylabel(app.UIAxes,'Theta2');
                    end
                end
            end
        end

        % Value changed function: l1
        function l1ValueChanged(app, event)
            value = app.l1.Value;
            app.L1 = value;
        end

        % Value changed function: l2
        function l2ValueChanged(app, event)
            value = app.l2.Value;
            app.L2 = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2 Link Serial Chain Robot Manipulator')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.trajMethod = uibuttongroup(app.UIFigure);
            app.trajMethod.Title = 'Method';
            app.carLinear = uitogglebutton(app.trajMethod);
            app.carLinear.Text = 'Cartesian Space Linear';
            app.carLinear.Value = true;
            app.jointLinear = uitogglebutton(app.trajMethod);
            app.jointLinear.Text = 'Joint Space Linear';
            app.carQuintic = uitogglebutton(app.trajMethod);
            app.carQuintic.Text = 'Cartesian Space Quintic';
            app.jointQuintic = uitogglebutton(app.trajMethod);
            app.jointQuintic.Text = 'Joint Space Quintic';
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.Items = {'Xe v/s t', 'Ye v/s t', 'Ye v/s Xe', 'Theta1 v/s t', 'Theta2 v/s t'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = 'Xe v/s t';
            app.StartPositiont0sLabel = uilabel(app.UIFigure);
            app.StartPositiont0sLabel.Text = 'Start Position (t=0s)';
            app.EndPositiont10sLabel = uilabel(app.UIFigure);
            app.EndPositiont10sLabel.Text = 'End Position (t=10s)';
            app.x0 = uieditfield(app.UIFigure, 'numeric');
            app.x0.ValueChangedFcn = createCallbackFcn(app, @x0ValueChanged, true);
            app.y0 = uieditfield(app.UIFigure, 'numeric');
            app.y0.ValueChangedFcn = createCallbackFcn(app, @y0ValueChanged, true);
            app.x10 = uieditfield(app.UIFigure, 'numeric');
            app.x10.ValueChangedFcn = createCallbackFcn(app, @x10ValueChanged, true);
            app.y10 = uieditfield(app.UIFigure, 'numeric');
            app.y10.ValueChangedFcn = createCallbackFcn(app, @y10ValueChanged, true);
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.UsingtheGUILabel = uilabel(app.UIFigure);
            app.UsingtheGUILabel.Text = 'Using the GUI:';
            app.EnterthestartandendpositioncoordinatesLabel = uilabel(app.UIFigure);
            app.EnterthestartandendpositioncoordinatesLabel.Text = '1. Enter the start and end position coordinates';
            app.SelectthemethodofestimatingthetrajectoryLabel = uilabel(app.UIFigure);
            app.SelectthemethodofestimatingthetrajectoryLabel.Text = '2. Select the method of estimating the trajectory';
            app.iCartesianspacelinearinterpolationmethodLabel = uilabel(app.UIFigure);
            app.iCartesianspacelinearinterpolationmethodLabel.Text = 'i. Cartesian space linear interpolation method';
            app.linearlyinterpolatestheendeffectorcoordinatesLabel = uilabel(app.UIFigure);
            app.linearlyinterpolatestheendeffectorcoordinatesLabel.Text = 'linearly interpolates the end effector co.ordinates';
            app.iiJointspacelinearinterpolationmethodLabel = uilabel(app.UIFigure);
            app.iiJointspacelinearinterpolationmethodLabel.Text = 'ii.Joint space linear interpolation method';
            app.linearlyinterpolatesthejointanglesLabel = uilabel(app.UIFigure);
            app.linearlyinterpolatesthejointanglesLabel.Text = 'linearly interpolates the joint angles';
            app.iiiCartesianspacequinticmethodfitsaquinticLabel = uilabel(app.UIFigure);
            app.iiiCartesianspacequinticmethodfitsaquinticLabel.Text = 'iii.Cartesian space quintic method fits a quintic';
            app.polynomialoverxvstyvstLabel = uilabel(app.UIFigure);
            app.polynomialoverxvstyvstLabel.Text = 'polynomial over x v/s t & y v/s t';
            app.ivJointspacequinticmethodfitsaquinticLabel = uilabel(app.UIFigure);
            app.ivJointspacequinticmethodfitsaquinticLabel.Text = 'iv.Joint space quintic method fits a quintic';
            app.polynomialovertheta1vsttheta2vstLabel = uilabel(app.UIFigure);
            app.polynomialovertheta1vsttheta2vstLabel.Text = 'polynomial over theta1 v/s t & theta2 v/s t';
            app.ConfigSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ConfigSwitch.Items = {'Crossed', 'Uncrossed'};
            app.ConfigSwitch.ValueChangedFcn = createCallbackFcn(app, @ConfigSwitchValueChanged, true);
            app.ConfigSwitch.Value = 'Crossed';
            app.PicktheparametersthatneedtobeplottedLabel = uilabel(app.UIFigure);
            app.PicktheparametersthatneedtobeplottedLabel.Text = '3. Pick the parameters that need to be plotted';
            app.PressPlottoplotandClearPlottocleartheplotLabel = uilabel(app.UIFigure);
            app.PressPlottoplotandClearPlottocleartheplotLabel.Text = '4. Press ''Plot'' to plot and ''Clear Plot'' to clear the plot';
            app.Link1lengthLabel = uilabel(app.UIFigure);
            app.Link1lengthLabel.Text = 'Link 1 length';
            app.Link2lengthLabel = uilabel(app.UIFigure);
            app.Link2lengthLabel.Text = 'Link 2 length';
            app.l1 = uieditfield(app.UIFigure, 'numeric');
            app.l1.ValueChangedFcn = createCallbackFcn(app, @l1ValueChanged, true);
            app.l2 = uieditfield(app.UIFigure, 'numeric');
            app.l2.ValueChangedFcn = createCallbackFcn(app, @l2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q2_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end